classdef fitUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        r2                  matlab.ui.control.NumericEditField
        rEditFieldLabel     matlab.ui.control.Label
        polyDeg             matlab.ui.control.DropDown
        BackgroundpolydegDropDown_2Label  matlab.ui.control.Label
        funType             matlab.ui.control.DropDown
        FunctiontypeLabel   matlab.ui.control.Label
        step                matlab.ui.control.NumericEditField
        StepEditFieldLabel  matlab.ui.control.Label
        fitRange            matlab.ui.control.Slider
        FitintervalaroundcenterSliderLabel  matlab.ui.control.Label
        minR2               matlab.ui.control.NumericEditField
        MinrLabel           matlab.ui.control.Label
        autofit             matlab.ui.control.CheckBox
        saveButton          matlab.ui.control.Button
        deleteButton        matlab.ui.control.Button
        fitButton           matlab.ui.control.Button
        maxPlotLabel        matlab.ui.control.Label
        plotNoLabel         matlab.ui.control.Label
        plotNum             matlab.ui.control.NumericEditField
        rightButton         matlab.ui.control.Button
        leftButton          matlab.ui.control.Button
        axes2               matlab.ui.control.UIAxes
        axes1               matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private, Dependent)
        currentIdx
    end

    properties (Access = private)
        rawData
        fmrObject
        idxVar
        idxVarVector
        fitParams
        uncertFitParams
        lastInitialParams = []
    end
    
    methods (Access = private)

        function chngPlot(app, delta)
            % Check new number is within limits
            nPlot = app.plotNum.Value + delta;
            rightLim = app.plotNum.Limits(2);
            if (nPlot < 1)
                nPlot = 1;
            elseif (nPlot > rightLim)
                nPlot = rightLim;
            end

            % Change plot number
            app.plotNum.Value = nPlot;

            % If plot number is at limit, gray out
            % corresponding arrow
            app.leftButton.Enable = "on";
            app.rightButton.Enable = "on";
            if (nPlot == 1)
                app.leftButton.Enable = "off";
            end
            if (nPlot == app.plotNum.Limits(2))
                app.rightButton.Enable = "off";
            end
        end
        
        function displayData(app)
            [xData, yData] = app.rawData.getResonancePlotData(app.idxVar,...
                                                              app.idxVarVector(app.currentIdx));
            plot(app.axes1, xData, yData, '.');
        end
    end

    methods
        function out = get.currentIdx(app)
            out = app.plotNum.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rawData, fmrObject, idxVar)
            % Save properties
            app.rawData = rawData;
            app.fmrObject = fmrObject;
            if (strcmp(idxVar,"Frequency"))
                app.idxVarVector = unique(rawData.getDataColumn("Frequency"));
            else
                app.idxVarVector = unique(rawData.getDataColumn("Field"));
            end

            % Write labels
            nPlots = numel(app.idxVarVector);
            app.plotNum.Limits = [1,nPlots];
            app.maxPlotLabel.Text = strjoin(["/", num2str(nPlots)]);
            app.chngPlot(0);

            % Initialize variables
            app.fitParams = zeros(nPlots, 9);
            app.uncertFitParams = zeros(nPlots, 9);

            % Plot data
                      
        end

        % Value changed function: autofit
        function autoFitChange(app, event)
            value = app.autofit.Value;
            app.fitRange.Enable = value;
            app.minR2.Enable = value;
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            chngPlot(app, app.step.Value);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            switch event.Key
                case 'leftarrow',  app.leftButtonPushed;
                case 'rightarrow', app.rightButtonPushed;
                case 'delete',     app.deleteButtonPushed();
            end
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            app.fitParams(app.currentIdx,:) = zeros(1, 9);
            app.uncertFitParams(app.currentIdx,:) = zeros(1, 9);
            
            % Remove plot
            app.axes2.Children.reset;
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            chngPlot(app, -app.step.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Current data to fit')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Gain (dB)')
            subtitle(app.axes1, 'Frequency: 0 GHz')
            app.axes2 = uiaxes(app.UIFigure);
            title(app.axes2, 'Current data to fit')
            xlabel(app.axes2, 'X')
            subtitle(app.axes2, 'Frequency: 0 GHz')
            app.leftButton = uibutton(app.UIFigure, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = '◀';
            app.rightButton = uibutton(app.UIFigure, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = '▶';
            app.plotNum = uieditfield(app.UIFigure, 'numeric');
            app.plotNum.Limits = [1 Inf];
            app.plotNum.ValueDisplayFormat = '%.0f';
            app.plotNum.Value = 1;
            app.plotNoLabel = uilabel(app.UIFigure);
            app.plotNoLabel.Text = 'Plot No.';
            app.maxPlotLabel = uilabel(app.UIFigure);
            app.maxPlotLabel.Text = '/ ';
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.Text = 'Fit data';
            app.deleteButton = uibutton(app.UIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'Delete current fit';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.Text = 'Save all';
            app.autofit = uicheckbox(app.UIFigure);
            app.autofit.ValueChangedFcn = createCallbackFcn(app, @autoFitChange, true);
            app.autofit.Text = 'Automatic fitting';
            app.MinrLabel = uilabel(app.UIFigure);
            app.MinrLabel.Text = 'Min. r²:';
            app.minR2 = uieditfield(app.UIFigure, 'numeric');
            app.minR2.Limits = [0 1];
            app.minR2.ValueDisplayFormat = '%.2f';
            app.minR2.Enable = 'off';
            app.minR2.Value = 0.85;
            app.FitintervalaroundcenterSliderLabel = uilabel(app.UIFigure);
            app.FitintervalaroundcenterSliderLabel.Text = 'Fit interval around center (%)';
            app.fitRange = uislider(app.UIFigure);
            app.fitRange.Enable = 'off';
            app.fitRange.Value = 20;
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step:';
            app.step = uieditfield(app.UIFigure, 'numeric');
            app.step.RoundFractionalValues = 'on';
            app.step.ValueDisplayFormat = '%.0f';
            app.step.Value = 1;
            app.FunctiontypeLabel = uilabel(app.UIFigure);
            app.FunctiontypeLabel.Text = 'Function type:';
            app.funType = uidropdown(app.UIFigure);
            app.funType.Items = {'Lorentzian', 'Voigt', 'Polar (Lorentz)'};
            app.funType.Value = 'Lorentzian';
            app.BackgroundpolydegDropDown_2Label = uilabel(app.UIFigure);
            app.BackgroundpolydegDropDown_2Label.Text = 'Background poly. deg.';
            app.polyDeg = uidropdown(app.UIFigure);
            app.polyDeg.Items = {'0', '1', '2'};
            app.polyDeg.Value = '1';
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r² =';
            app.r2 = uieditfield(app.UIFigure, 'numeric');
            app.r2.ValueDisplayFormat = '%.3f';
            app.r2.Editable = 'off';
            app.r2.Value = 0.85;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fitUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end